classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlikMenu                     matlab.ui.container.Menu
        ZamknijMenu                  matlab.ui.container.Menu
        PomocMenu                    matlab.ui.container.Menu
        OaplikacjiMenu               matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        WysokowalcaEditField         matlab.ui.control.NumericEditField
        WysokowalcaEditFieldLabel    matlab.ui.control.Label
        KsztatCiarkaDropDown         matlab.ui.control.DropDown
        KsztatCiarkaDropDownLabel    matlab.ui.control.Label
        WLabel                       matlab.ui.control.Label
        TLabelValue                  matlab.ui.control.Label
        TLabel                       matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        FizyczneButton               matlab.ui.control.RadioButton
        MatematyczneButton           matlab.ui.control.RadioButton
        Slider                       matlab.ui.control.Slider
        TypWahadaLabel               matlab.ui.control.Label
        KtwychyleniawahadaEditField  matlab.ui.control.NumericEditField
        KtwychyleniawahadaEditFieldLabel  matlab.ui.control.Label
        PromieciarkamEditField       matlab.ui.control.NumericEditField
        PromieciarkamEditFieldLabel  matlab.ui.control.Label
        DugonicimEditField           matlab.ui.control.NumericEditField
        Slider_2                     matlab.ui.control.Slider
        DugonicimEditFieldLabel      matlab.ui.control.Label
        wykres                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        length % Długość nici wachadła
        radius = .1 % promień ciężarka (kuli/walca)
        height = .1 % wysokość ciężarka (walca)
        Timer % timer for plot updating
        phi0 = 30 % kąt wychylenia wachadła
        pendulumType = 1 % 1 = matematyczne 2 = fizyczne
        % progress is an intager clamped on 100.
        progress
        % direction. if 0 forward, if not (expected 1) backward
        direction
        % nos is the max number of steps for our animation
        nos = 200 % number of steps of animation. less = faster, looks worse
        precalculated = {}
        limits = {} % limits for 
        weightType = 1;
    end
    
    methods (Access = private)
        
        function  updatePlot(app)
            if not(app.precalculated.done)
                return
            end

            plot(app.wykres,...
                app.precalculated.x,app.precalculated.y,'--',...
                [0], [app.precalculated.r], 'o',...
                [0,app.precalculated.xPt(app.progress+1)],[app.precalculated.r,app.precalculated.yPt(app.progress+1)],'r-',...
                app.precalculated.xm(:,app.progress+1),...
                app.precalculated.ym(:,app.progress+1),...
                app.precalculated.lineType);
                
            xlim(app.wykres,[-app.limits.x, app.limits.x]);
            ylim(app.wykres, [app.limits.yMin, app.limits.yMax]);
            set(app.wykres,'DataAspectRatio', [1,1,1]);
            app.makeProgress()
        end
        
        function makeProgress(app)
            if app.direction == 0 
                if app.progress == app.nos
                    app.progress = app.progress - 1;
                    app.direction = 1;
                    return
                end
                app.progress = app.progress + 1;
            else
                if app.progress == 0;
                    app.progress = app.progress + 1;
                    app.direction = 0;
                    return
                end
                app.progress = app.progress - 1;
            end
        end
        
        function startAnimation(app, FPS)
            app.precalculated.done = false; % invalidate current animation
            if isequal(get(app.Timer, 'Running'), 'on')
                stop(app.Timer);
            end
            delete(app.Timer);
            app.precalculatePlot();
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period',1/FPS,'TimerFcn', @(~,~)updatePlot(app));
            start(app.Timer)
        end
        
        function refreshAnimation(app)
            %calculate new FPS
            % 1. check which type of pendulum we're going to symulate
            g = 9.81; % const
            T=0;
            switch app.pendulumType
                case 1
                    T = 2*pi*sqrt(app.length/g);
                case 2
                    switch app.weightType
                        case 1
                            %   I od kuli           I steinera
                            I = 2/5 *app.radius^2 + app.length^2; % in fact: I/m
                        case 2
                            %   I od walca wzdłóż osi X
                            %   ISteinera     przesunięcie steinera do
                            I = 1/12 *(3*app.radius^2 + app.height^2) + (app.length + app.height/2)^2;
                        case 3
                            I = 1/2*app.radius^2 + (app.radius + app.length)^2;
                        case 4
                            I = 1/12*(app.radius^2 + app.height^2) + (app.height/2 + app.length)^2;
                    end
                    T = 2*pi*sqrt(I/(app.length*g));
            end 
            % start animation
            app.TLabelValue.Text = T + "s";
            app.startAnimation(app.nos/T);
        end
        
        function precalculatePlot(app)
            % DISCLAIMER: this function may lag on some machines
            % however it does not on mine, so i leave it as-is for now.
            % optimalization perspective available.
            phi0=deg2rad(app.phi0);
            r=app.length; % const
            
            phiXY = -phi0:0.01:phi0;
            %app.precalculated.x = -r*cos(phi0-pi/2):.01:r*cos(phi0-pi/2);
            app.precalculated.x = r*cos(phiXY-pi/2);
            app.precalculated.y=-sqrt(r^2-app.precalculated.x.^2)+r;
            
            p = 0:1:app.nos;
            phi=-phi0*cos(2*pi*p/app.nos)-pi/2; % wyprowadzone w zeszycie ;-)
            app.precalculated.xPt = r*cos(phi);
            app.precalculated.yPt = r*sin(phi)+r; % translacja do punktu zaczepienia
            
            %ciężarek
            switch (app.pendulumType) 
                case 1
                    app.precalculated.xm = app.precalculated.xPt;
                    app.precalculated.ym = app.precalculated.yPt;
                    app.precalculated.lineType = 'o';
                    app.limits.x = r*sin(phi0);
                    app.limits.yMin = 0;
                    app.limits.yMax = app.length;
                case 2
                    switch app.weightType
                        case {1, 3}
                            phi = 0:.1:2*pi;
                            rd = app.radius;
                            app.precalculated.xm = [];
                            app.precalculated.ym = [];
                            xm = rd*cos(phi);
                            ym = rd*sin(phi);
                            for i = 1:1:app.nos+1
                                app.precalculated.xm(:,i) = xm+app.precalculated.xPt(i);
                                app.precalculated.ym(:,i) = ym+app.precalculated.yPt(i);
                            end
                            app.precalculated.lineType = 'k-';
                            app.limits.x = app.radius+r*sin(phi0);
                            app.limits.yMin = -app.radius;
                            app.limits.yMax = max(app.length, app.radius);
                        case {2, 4}
                            r = app.radius;
                            h = app.height;
                            if app.weightType == 4
                                r = r/2;
                                h = r*2;
                            end
                            phi = phi+pi/2;
                            x0 = app.precalculated.xPt;
                            y0 = app.precalculated.yPt;

                            ax = x0 + r*cos(phi);
                            ay = y0 + r * sin(phi);

                            bx = x0 - r*cos(phi);
                            by = y0 - r * sin(phi);

                            cx = bx + h * sin(phi);
                            cy = by - h * cos(phi);

                            dx = ax + h * sin(phi);
                            dy = ay - h * cos(phi);
                            
                            app.precalculated.xm = [ax;bx;cx;dx;ax];
                            app.precalculated.ym = [ay;by;cy;dy;ay];

                            app.precalculated.lineType = 'k-';
                            app.limits.yMin = min(-h, app.length*(1-cos(phi0)) - r*sin(phi0) - h*cos(phi0)); % wyprowadzenie w zeszycie
                            %app.limits.yMax = max(app.length, (-1)*(app.limits.yMin));
                            app.limits.yMax = max(app.length, r*sin(phi0));
                            app.limits.x = (app.length+h) * sin(phi0) + r*cos(phi0);
                    end
            end
            app.precalculated.r = app.length;
            app.precalculated.done = true;
        end
        
        function WLabelUpdate(app)
            PHI_CRIT = 30;
            if app.phi0 > PHI_CRIT
                app.WLabel.Visible = true;
                return
            end

            app.WLabel.Visible = false;
        end
        
        function pendulumTypeDynamicVisibility(app, visible)
            app.PromieciarkamEditField.Visible = visible;
            app.PromieciarkamEditFieldLabel.Visible = visible;
    
            app.KsztatCiarkaDropDown.Visible = visible;
            app.KsztatCiarkaDropDownLabel.Visible = visible;

            app.WysokowalcaEditField.Visible = visible;
            app.WysokowalcaEditFieldLabel.Visible = visible;
        end
        
        function updateLayout(app)
            switch app.pendulumType
                case 1
                    app.pendulumTypeDynamicVisibility(false);
                case 2
                    app.pendulumTypeDynamicVisibility(true);
                    switch app.weightType
                        case 1
                            app.WysokowalcaEditField.Visible = false;
                            app.WysokowalcaEditFieldLabel.Visible = false;

                            app.PromieciarkamEditFieldLabel.Text = "Promień Ciężarka [m]";
                        case {2, 3}
        	                app.PromieciarkamEditFieldLabel.Text = "Promień Podstawy [m]";
                            app.WysokowalcaEditFieldLabel.Text = "Wysokość Walca [m]";
                        case 4
                       	    app.WysokowalcaEditField.Visible = false;
                            app.WysokowalcaEditFieldLabel.Visible = false; 

                            app.PromieciarkamEditFieldLabel.Text = "Długość krawędzi [m]"
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateLayout();
            app.precalculated.done = false;
            app.progress = 0;
            app.direction = 0;
            app.length=1;
            app.WLabelUpdate();
            app.refreshAnimation();
        end

        % Menu selected function: ZamknijMenu
        function ZamknijMenuSelected(app, event)
            app.UIFigureCloseRequest(); 
        end

        % Value changed function: DugonicimEditField
        function DugonicimEditFieldValueChanged(app, event)
            app.length = app.DugonicimEditField.Value;
            app.refreshAnimation();
        end

        % Value changed function: PromieciarkamEditField
        function PromieciarkamEditFieldValueChanged(app, event)
            app.radius = app.PromieciarkamEditField.Value;
            app.refreshAnimation();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer);
            delete(app)
        end

        % Value changed function: KtwychyleniawahadaEditField
        function KtwychyleniawahadaEditFieldValueChanged(app, event)
            app.phi0 = app.KtwychyleniawahadaEditField.Value;
            app.WLabelUpdate();
            app.refreshAnimation();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            % why the he*l there is no way to obtain button number
            % in a normal way?
            idxTbl = ismember(app.ButtonGroup.Buttons, selectedButton);
            buttonNumber=-1;
            len = size(idxTbl); % I need to do this here because len is still a vector (WHY?) and I can't proceed this in one line
            for i = 1:1:(len(2))
                if idxTbl(i)
                    buttonNumber = i;
                    break;
                end
            end

            app.pendulumType = buttonNumber;

            app.updateLayout();
            app.refreshAnimation();
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.length = app.Slider_2.Value;
            app.refreshAnimation();
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.DugonicimEditField.Value = changingValue;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.phi0 = value;
            app.WLabelUpdate();
            app.refreshAnimation();
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.KtwychyleniawahadaEditField.Value = event.Value;
        end

        % Value changed function: KsztatCiarkaDropDown
        function KsztatCiarkaDropDownValueChanged(app, event)
            value = app.KsztatCiarkaDropDown.ValueIndex;
            app.weightType = value;
            % now I need to call this magic thing
            app.updateLayout();
            app.refreshAnimation();
        end

        % Value changed function: WysokowalcaEditField
        function WysokowalcaEditFieldValueChanged(app, event)
            value = app.WysokowalcaEditField.Value;
            app.height = value;
            app.refreshAnimation();
        end

        % Menu selected function: OaplikacjiMenu
        function OaplikacjiMenuSelected(app, event)
            omnie = sprintf("Autor: Maciej Sz.\n" + ...
                "Wersja: 1.0.0\n" + ...
                "Data wydania: 08.06.2024 23:15 CEST\n" + ...
                "\n" + ...
                "EntulumPendulum to aplikacja utworzona w MatLab'ie.\n" + ...
                "Główne funkcjonalności to:\n" + ...
                "- w czasie rzeczywistym symulacja ruchu wachadła\n" + ...
                "- możliwość wyboru typu wahadła (matematyczne/fizyczne)\n" + ...
                "- możliwość wyboru kształtu ciężarka");
            uialert(app.UIFigure, omnie, "O Aplikacji", 'icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PlikMenu = uimenu(app.UIFigure);
            app.PlikMenu.Text = 'Plik';
            app.ZamknijMenu = uimenu(app.PlikMenu);
            app.ZamknijMenu.MenuSelectedFcn = createCallbackFcn(app, @ZamknijMenuSelected, true);
            app.ZamknijMenu.Accelerator = 'q';
            app.ZamknijMenu.Text = 'Zamknij';
            app.PomocMenu = uimenu(app.UIFigure);
            app.PomocMenu.Text = 'Pomoc';
            app.OaplikacjiMenu = uimenu(app.PomocMenu);
            app.OaplikacjiMenu.MenuSelectedFcn = createCallbackFcn(app, @OaplikacjiMenuSelected, true);
            app.OaplikacjiMenu.Text = 'O aplikacji';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.wykres = uiaxes(app.GridLayout);
            title(app.wykres, 'Wachadło')
            xlabel(app.wykres, 'X')
            ylabel(app.wykres, 'Y')
            zlabel(app.wykres, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DugonicimEditFieldLabel = uilabel(app.GridLayout2);
            app.DugonicimEditFieldLabel.Text = 'Długość nici [m]';
            app.Slider_2 = uislider(app.GridLayout2);
            app.Slider_2.Limits = [0.01 50];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 1;
            app.DugonicimEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DugonicimEditField.Limits = [0.01 Inf];
            app.DugonicimEditField.ValueChangedFcn = createCallbackFcn(app, @DugonicimEditFieldValueChanged, true);
            app.DugonicimEditField.Value = 1;
            app.PromieciarkamEditFieldLabel = uilabel(app.GridLayout2);
            app.PromieciarkamEditFieldLabel.Text = 'Promień ciężarka [m]';
            app.PromieciarkamEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PromieciarkamEditField.Limits = [0 Inf];
            app.PromieciarkamEditField.ValueChangedFcn = createCallbackFcn(app, @PromieciarkamEditFieldValueChanged, true);
            app.PromieciarkamEditField.Value = 0.1;
            app.KtwychyleniawahadaEditFieldLabel = uilabel(app.GridLayout2);
            app.KtwychyleniawahadaEditFieldLabel.Text = 'Kąt wychylenia wahadła';
            app.KtwychyleniawahadaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KtwychyleniawahadaEditField.Limits = [1 90];
            app.KtwychyleniawahadaEditField.ValueChangedFcn = createCallbackFcn(app, @KtwychyleniawahadaEditFieldValueChanged, true);
            app.KtwychyleniawahadaEditField.Value = 30;
            app.TypWahadaLabel = uilabel(app.GridLayout2);
            app.TypWahadaLabel.Text = 'Typ Wahadła';
            app.Slider = uislider(app.GridLayout2);
            app.Slider.Limits = [1 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 30;
            app.ButtonGroup = uibuttongroup(app.GridLayout2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MatematyczneButton = uiradiobutton(app.ButtonGroup);
            app.MatematyczneButton.Text = 'Matematyczne';
            app.MatematyczneButton.Value = true;
            app.FizyczneButton = uiradiobutton(app.ButtonGroup);
            app.FizyczneButton.Text = 'Fizyczne';
            app.TLabel = uilabel(app.GridLayout2);
            app.TLabel.Text = 'T=';
            app.TLabelValue = uilabel(app.GridLayout2);
            app.TLabelValue.Text = '---';
            app.WLabel = uilabel(app.GridLayout2);
            app.WLabel.Interpreter = 'latex';
            app.WLabel.Text = {'UWAGA: Dla większych kątów wychylenia,, wzór na'; 'okres drgań wahadeł nie będzie w pełi oddawał stanu'; 'faktycznego ($sin\alpha \approx \alpha$)'};
            app.KsztatCiarkaDropDownLabel = uilabel(app.GridLayout2);
            app.KsztatCiarkaDropDownLabel.Text = 'Kształt Ciężarka';
            app.KsztatCiarkaDropDown = uidropdown(app.GridLayout2);
            app.KsztatCiarkaDropDown.Items = {'Kula', 'Walec (równoległy do nici)', 'Walec (prostopadle,  oś przez podstawy)', 'Sześcian'};
            app.KsztatCiarkaDropDown.ValueChangedFcn = createCallbackFcn(app, @KsztatCiarkaDropDownValueChanged, true);
            app.KsztatCiarkaDropDown.Value = 'Kula';
            app.WysokowalcaEditFieldLabel = uilabel(app.GridLayout2);
            app.WysokowalcaEditFieldLabel.Text = 'Wysokość walca';
            app.WysokowalcaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WysokowalcaEditField.ValueChangedFcn = createCallbackFcn(app, @WysokowalcaEditFieldValueChanged, true);
            app.WysokowalcaEditField.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end